classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RecordingsTab                  matlab.ui.container.Tab
        LoadGroupButton                matlab.ui.control.Button
        RemoverecordingsButton         matlab.ui.control.Button
        PlottingPanel                  matlab.ui.container.Panel
        VEPButton                      matlab.ui.control.Button
        RemoveRecFromPlotListButton    matlab.ui.control.Button
        RemoveGroupFromPlotListButton  matlab.ui.control.Button
        prevsduringFFTButton           matlab.ui.control.Button
        GroupsListBox_2Label           matlab.ui.control.Label
        GroupsPlotListBox              matlab.ui.control.ListBox
        RecordingsListBox_2Label       matlab.ui.control.Label
        RecordingPlotListBox           matlab.ui.control.ListBox
        groupbyButtonGroup             matlab.ui.container.ButtonGroup
        RecordingsButton               matlab.ui.control.RadioButton
        GroupsButton                   matlab.ui.control.RadioButton
        RecordingsListBoxLabel         matlab.ui.control.Label
        RecordingsListBox              matlab.ui.control.ListBox
        GroupsListBoxLabel             matlab.ui.control.Label
        GroupsListBox                  matlab.ui.control.ListBox
        AddRecToPlotListButton         matlab.ui.control.Button
        AddGroupToPlotListButton       matlab.ui.control.Button
        ParametersTab                  matlab.ui.container.Tab
        stimcodesEditFieldLabel        matlab.ui.control.Label
        stimcodesEditField             matlab.ui.control.NumericEditField
        FFTPanel                       matlab.ui.container.Panel
        prestimtimesPanel              matlab.ui.container.Panel
        fromEditFieldLabel             matlab.ui.control.Label
        FFTpreMinTimeEdit              matlab.ui.control.NumericEditField
        sLabel                         matlab.ui.control.Label
        sLabel_4                       matlab.ui.control.Label
        toEditField_3Label             matlab.ui.control.Label
        FFTpreMaxTimeEdit              matlab.ui.control.NumericEditField
        prestimtimesPanel_2            matlab.ui.container.Panel
        fromEditField_2Label           matlab.ui.control.Label
        FFTduringMinTimeEdit           matlab.ui.control.NumericEditField
        sLabel_5                       matlab.ui.control.Label
        sLabel_6                       matlab.ui.control.Label
        toEditField_4Label             matlab.ui.control.Label
        FFTduringMaxTimeEdit           matlab.ui.control.NumericEditField
        FrequenciesPanel               matlab.ui.container.Panel
        fromEditField_3Label           matlab.ui.control.Label
        FFTmaxFreqEdit                 matlab.ui.control.NumericEditField
        HzLabel                        matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        toEditField_5Label             matlab.ui.control.Label
        FFTminFreqEdit                 matlab.ui.control.NumericEditField
        VEPPanel                       matlab.ui.container.Panel
        timesPanel                     matlab.ui.container.Panel
        fromEditField_4Label           matlab.ui.control.Label
        VEPminTimetEdit                matlab.ui.control.NumericEditField
        sLabel_7                       matlab.ui.control.Label
        sLabel_8                       matlab.ui.control.Label
        toEditField_6Label             matlab.ui.control.Label
        VEPmaxTimeEdit                 matlab.ui.control.NumericEditField
        ComparestimsCheckBox           matlab.ui.control.CheckBox
    end


    properties (Access = public)
        recordings = {};
        folders = {};
    end


    methods (Access = private)

        % Button pushed function: LoadGroupButton
        function LoadGroupButtonPushed(app, event)
            %carga los VEPs de los registros de los grupos de neuronas seleccionados
            [files, path] = uigetfile('*.mat', 'seleccione los grupos de neuronas', 'MultiSelect', 'on');
            if ~iscell(files)
                files = {files};
            end
            nFiles = length(files);
            groupList = {};
            nameList = {};
            %recorro la lista de archivos. Voy a cargar los VEPs a partir de la lista de neuronas.
            regsList = VEPrecording.empty;
            for f = 1:nFiles
                load(fullfile(path, files{f}))
                groupFileList = getFileList(neurons);
                
                regs = loadVEPs(groupFileList);
                nl = getRegsNameList(regs);
                group = files{f}(1:end-4);
                %genero arrays con los nombres de los registros y de los grupos de neuronas que contienen
                for i = 1:length(nl)
                    nameList{end+1} = nl{i};
                    groupList{end+1} = {group};
                end
                if isempty(regsList)
                    regsList = regs;
                else
                    regsList =  [regsList, regs];
                end
            end
            %elimino los registros repetidos
            regsList = getUniqueRegs(regsList);
            newRecsNames = getRegsNameList(regsList);
            %y ahora los incoroporo a la base de datos de la app.
            if isempty(app.recordings)
                app.recordings = regsList;
            else
                crrntRecNames = getRegsNameList(app.recordings);
                for r = 1:length(regsList)
                    if ~any(strcmp(crrntRecNames,  newRecsNames{r}))
                        app.recordings(end+1) = regsList(r);
                    end
                end
            end
            
            %Cargo los grupos a los registros            
            for r = 1:length(app.recordings)
                for n = 1:length(nameList)
                    name = nameList{n};
                    if strcmp(app.recordings(r).name, name)
                        app.recordings(r).addGroup(groupList{n});
                    end
                end
            end
            
            app.RecordingsListBox.Items = getRegsNameList(app.recordings);
            app.GroupsListBox.Items = getRegsGroupsList(app.recordings);            
        end

        % Button pushed function: AddRecToPlotListButton
        function AddRecToPlotListButtonPushed(app, event)
            selected = app.RecordingsListBox.Value;
            for s = 1:length(selected)
                if ~sum(strcmp(app.RecordingPlotListBox.Items, selected(s)))
                    app.RecordingPlotListBox.Items = [app.RecordingPlotListBox.Items selected(s)];
                end
            end
            
        end

        % Button pushed function: AddGroupToPlotListButton
        function AddGroupToPlotListButtonPushed(app, event)
            selected = app.GroupsListBox.Value;
            for s = 1:length(selected)
                if ~sum(strcmp(app.GroupsPlotListBox.Items, selected(s)))
                    app.GroupsPlotListBox.Items = [app.GroupsPlotListBox.Items selected(s)];
                end
            end
        end

        % Button pushed function: RemoveGroupFromPlotListButton
        function RemoveGroupFromPlotListButtonPushed(app, event)
            selected = app.GroupsPlotListBox.Value;
            for s = 1:length(selected)
                ind = find(strcmp(app.GroupsPlotListBox.Items, selected{s}));
                app.GroupsPlotListBox.Items(ind) = [];
            end
        end

        % Button pushed function: RemoveRecFromPlotListButton
        function RemoveRecFromPlotListButtonPushed(app, event)
            selected = app.RecordingPlotListBox.Value;
            for s = 1:length(selected)
                ind = find(strcmp(app.RecordingPlotListBox.Items, selected{s}));
                app.RecordingPlotListBox.Items(ind) = [];
            end
        end

        % Button pushed function: RemoverecordingsButton
        function RemoverecordingsButtonPushed(app, event)
            selected = app.RecordingsListBox.Value;
            %elimino los registros seleccionados
            for s = 1:length(selected)
                ind = find(strcmp(app.RecordingPlotListBox.Items, selected{s}));
                app.RecordingPlotListBox.Items(ind) = [];
                ind = find(strcmp(app.RecordingsListBox.Items, selected{s}));
                app.RecordingsListBox.Items(ind) = [];
                nameList = getRegsNameList(app.recordings);
                ind = find(strcmp(nameList, selected{s}));
                app.recordings(ind) = [];
            end
            
            
            %ahora voy a checkear si al remover los registros tambien elimine algun
            %grupo por completo.
            crrntGrps = getRegsGroupsList(app.recordings);
            grps = app.GroupsListBox.Items;
            pltGrps = app.GroupsPlotListBox.Items;

            for g = length(pltGrps):-1:1
                if ~any(strcmp(crrntGrps, pltGrps(g)))
                    pltGrps(g) = [];
                end
            end
            for g = length(grps):-1:1
                if ~any(strcmp(crrntGrps, grps(g)))
                    grps(g) = [];
                end
            end
            app.GroupsListBox.Items = grps;
            app.GroupsPlotListBox.Items = pltGrps;                        
        end

        % Button pushed function: VEPButton
        function VEPButtonPushed(app, event)
            assignin('base', 'recordings', app.recordings)
            disp(getRegsNameList(app.recordings))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordingsTab = uitab(app.TabGroup);
            app.RecordingsTab.Title = 'Recordings';
            app.LoadGroupButton = uibutton(app.RecordingsTab, 'push');
            app.LoadGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroupButtonPushed, true);
            app.LoadGroupButton.Text = {'Load '; 'Group'};
            app.RemoverecordingsButton = uibutton(app.RecordingsTab, 'push');
            app.RemoverecordingsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverecordingsButtonPushed, true);
            app.RemoverecordingsButton.Text = {'Remove '; 'recordings'};
            app.PlottingPanel = uipanel(app.RecordingsTab);
            app.PlottingPanel.Title = 'Plotting';
            app.VEPButton = uibutton(app.PlottingPanel, 'push');
            app.VEPButton.ButtonPushedFcn = createCallbackFcn(app, @VEPButtonPushed, true);
            app.VEPButton.Text = 'VEP';
            app.RemoveRecFromPlotListButton = uibutton(app.PlottingPanel, 'push');
            app.RemoveRecFromPlotListButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRecFromPlotListButtonPushed, true);
            app.RemoveRecFromPlotListButton.Text = 'Remove';
            app.RemoveGroupFromPlotListButton = uibutton(app.PlottingPanel, 'push');
            app.RemoveGroupFromPlotListButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupFromPlotListButtonPushed, true);
            app.RemoveGroupFromPlotListButton.Text = 'Remove';
            app.prevsduringFFTButton = uibutton(app.PlottingPanel, 'push');
            app.prevsduringFFTButton.Text = 'pre vs during FFT';
            app.GroupsListBox_2Label = uilabel(app.PlottingPanel);
            app.GroupsListBox_2Label.Text = 'Groups';
            app.GroupsPlotListBox = uilistbox(app.PlottingPanel);
            app.GroupsPlotListBox.Items = {};
            app.GroupsPlotListBox.Multiselect = 'on';
            app.GroupsPlotListBox.Value = {};
            app.RecordingsListBox_2Label = uilabel(app.PlottingPanel);
            app.RecordingsListBox_2Label.Text = 'Recordings';
            app.RecordingPlotListBox = uilistbox(app.PlottingPanel);
            app.RecordingPlotListBox.Items = {};
            app.RecordingPlotListBox.Multiselect = 'on';
            app.RecordingPlotListBox.Value = {};
            app.groupbyButtonGroup = uibuttongroup(app.PlottingPanel);
            app.groupbyButtonGroup.Title = 'group by';
            app.RecordingsButton = uiradiobutton(app.groupbyButtonGroup);
            app.RecordingsButton.Text = 'Recordings';
            app.RecordingsButton.Value = true;
            app.GroupsButton = uiradiobutton(app.groupbyButtonGroup);
            app.GroupsButton.Text = 'Groups';
            app.RecordingsListBoxLabel = uilabel(app.RecordingsTab);
            app.RecordingsListBoxLabel.Text = 'Recordings';
            app.RecordingsListBox = uilistbox(app.RecordingsTab);
            app.RecordingsListBox.Items = {};
            app.RecordingsListBox.Multiselect = 'on';
            app.RecordingsListBox.Value = {};
            app.GroupsListBoxLabel = uilabel(app.RecordingsTab);
            app.GroupsListBoxLabel.Text = 'Groups';
            app.GroupsListBox = uilistbox(app.RecordingsTab);
            app.GroupsListBox.Items = {};
            app.GroupsListBox.Multiselect = 'on';
            app.GroupsListBox.Value = {};
            app.AddRecToPlotListButton = uibutton(app.RecordingsTab, 'push');
            app.AddRecToPlotListButton.ButtonPushedFcn = createCallbackFcn(app, @AddRecToPlotListButtonPushed, true);
            app.AddRecToPlotListButton.Text = {'Add'; 'recordings'};
            app.AddGroupToPlotListButton = uibutton(app.RecordingsTab, 'push');
            app.AddGroupToPlotListButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupToPlotListButtonPushed, true);
            app.AddGroupToPlotListButton.Text = {'Add'; 'groups'};
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.stimcodesEditFieldLabel = uilabel(app.ParametersTab);
            app.stimcodesEditFieldLabel.Text = 'stim codes';
            app.stimcodesEditField = uieditfield(app.ParametersTab, 'numeric');
            app.stimcodesEditField.Value = 2;
            app.FFTPanel = uipanel(app.ParametersTab);
            app.FFTPanel.Title = 'FFT';
            app.prestimtimesPanel = uipanel(app.FFTPanel);
            app.prestimtimesPanel.Title = 'pre stim times';
            app.fromEditFieldLabel = uilabel(app.prestimtimesPanel);
            app.fromEditFieldLabel.Text = 'from';
            app.FFTpreMinTimeEdit = uieditfield(app.prestimtimesPanel, 'numeric');
            app.FFTpreMinTimeEdit.Value = -10;
            app.sLabel = uilabel(app.prestimtimesPanel);
            app.sLabel.Text = 's';
            app.sLabel_4 = uilabel(app.prestimtimesPanel);
            app.sLabel_4.Text = 's';
            app.toEditField_3Label = uilabel(app.prestimtimesPanel);
            app.toEditField_3Label.Text = 'to';
            app.FFTpreMaxTimeEdit = uieditfield(app.prestimtimesPanel, 'numeric');
            app.prestimtimesPanel_2 = uipanel(app.FFTPanel);
            app.prestimtimesPanel_2.Title = 'pre stim times';
            app.fromEditField_2Label = uilabel(app.prestimtimesPanel_2);
            app.fromEditField_2Label.Text = 'from';
            app.FFTduringMinTimeEdit = uieditfield(app.prestimtimesPanel_2, 'numeric');
            app.FFTduringMinTimeEdit.Value = 3;
            app.sLabel_5 = uilabel(app.prestimtimesPanel_2);
            app.sLabel_5.Text = 's';
            app.sLabel_6 = uilabel(app.prestimtimesPanel_2);
            app.sLabel_6.Text = 's';
            app.toEditField_4Label = uilabel(app.prestimtimesPanel_2);
            app.toEditField_4Label.Text = 'to';
            app.FFTduringMaxTimeEdit = uieditfield(app.prestimtimesPanel_2, 'numeric');
            app.FFTduringMaxTimeEdit.Value = 13;
            app.FrequenciesPanel = uipanel(app.FFTPanel);
            app.FrequenciesPanel.Title = 'Frequencies';
            app.fromEditField_3Label = uilabel(app.FrequenciesPanel);
            app.fromEditField_3Label.Text = 'from';
            app.FFTmaxFreqEdit = uieditfield(app.FrequenciesPanel, 'numeric');
            app.HzLabel = uilabel(app.FrequenciesPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FrequenciesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.toEditField_5Label = uilabel(app.FrequenciesPanel);
            app.toEditField_5Label.Text = 'to';
            app.FFTminFreqEdit = uieditfield(app.FrequenciesPanel, 'numeric');
            app.FFTminFreqEdit.Value = 30;
            app.VEPPanel = uipanel(app.ParametersTab);
            app.VEPPanel.Title = 'VEP';
            app.timesPanel = uipanel(app.VEPPanel);
            app.timesPanel.Title = 'times';
            app.fromEditField_4Label = uilabel(app.timesPanel);
            app.fromEditField_4Label.Text = 'from';
            app.VEPminTimetEdit = uieditfield(app.timesPanel, 'numeric');
            app.VEPminTimetEdit.Value = -10;
            app.sLabel_7 = uilabel(app.timesPanel);
            app.sLabel_7.Text = 's';
            app.sLabel_8 = uilabel(app.timesPanel);
            app.sLabel_8.Text = 's';
            app.toEditField_6Label = uilabel(app.timesPanel);
            app.toEditField_6Label.Text = 'to';
            app.VEPmaxTimeEdit = uieditfield(app.timesPanel, 'numeric');
            app.VEPmaxTimeEdit.Value = 20;
            app.ComparestimsCheckBox = uicheckbox(app.ParametersTab);
            app.ComparestimsCheckBox.Text = 'Compare stims';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end