classdef dataAnalysisGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        loadData          matlab.ui.control.Button
        clusterEdit       matlab.ui.control.EditField
        clusterTxt        matlab.ui.control.Label
        stimByCodeTxt     matlab.ui.control.Label
        stimByCodeEdit    matlab.ui.control.EditField
        stimSugestionTxt  matlab.ui.control.Label
        plotRasters       matlab.ui.control.Button
        pathTxt           matlab.ui.control.Label
        tPreStimEdit      matlab.ui.control.EditField
        tPreStimTxt       matlab.ui.control.Label
        tPostStimTxt      matlab.ui.control.Label
        tPostStimEdit     matlab.ui.control.EditField
        stimByListTxt     matlab.ui.control.Label
        stimByListEdit    matlab.ui.control.EditField
        monitorDerecho    matlab.ui.control.CheckBox
        monitorIzquierdo  matlab.ui.control.CheckBox
        textMonitores     matlab.ui.control.Label
        text12            matlab.ui.control.Label
        binSize           matlab.ui.control.EditField
        saveCluster       matlab.ui.control.Button
        clustersSaved     matlab.ui.control.Label
        openNeuronViewer  matlab.ui.control.Button
        add2List_push     matlab.ui.control.Button
    end

    
    methods (Access = private)
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function dataAnalysisGUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to dataAnalysisGUI (see VARARGIN)
%             
%             % Choose default command line output for dataAnalysisGUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: add2List_push
        function add2List_push_Callback(app, event)
            % hObject    handle to add2List_push (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file,path] = uigetfile('*mat');
            cd(path)
            %cargo la lista de neuronas a la que quiero sumar mi neurona
            loaded = load(file);
            %obtengo el nombre de la neurona con la GUI clusterNameGUI
            neuron.name = clusterNameGUI;
            %si me devuelve un vector vacío es que se canceló y salgo de la función
            if isempty(neuron.name)
                return;
            end
            %cargo los datos de la neurona
            neuron.data = app.data{app.cluster};
            neuron.file = app.pathTxt.String;
            neuron.cluster = app.cluster;
            neuron.Estimulos = app.Estimulos;
            neuron.Monitores = app.Monitores;
            
            %checkeo que no haya una neurona en la lista con el mismo nombre
            for i = 1:length(loaded.neurons)
                if strcmp(neuron.name, loaded.neurons{i}.name)
                    error('Ya hay una neurona con ese nombre en la lista')
                end
            end
            
            %neurons es un cell array que va a contener a todas las neuronas a guardar
            neurons =loaded.neurons;
            %y le agrego mi nueva neurona
            neurons = [neurons, neuron];
            %guardo las neuronas
            save([path, '/', file], 'neurons')
            fprintf('\nNeurona agregada!\n\n')
        end

        % Value changed function: clusterEdit
        function clusterEdit_Callback(app, event)
            % hObject    handle to clusterEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of clusterEdit as text
            %        str2double(get(hObject,'String')) returns contents of clusterEdit as a double
            app.cluster = str2double(get(hObject, 'string'));
            guidata(hObject, handles);
        end

        % Button pushed function: loadData
        function loadData_Callback(app, event)
            % hObject    handle to loadData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Seteo el path al que voy a ir a buscar los archivos
            path = uigetdir('D://Labo//registros');
            app.path = path;
            loadingDataStr = 'Loading Data...';
            set(app.pathTxt, 'String', loadingDataStr);
            guidata(hObject, handles);
            
            %Cargo los datos:
            %"data" tiene los tiempos de cada spike. Cada celda corresponde a un
            % cluster distinto. Las dimensiones de data son 1 x #de clusters:
            app.data = loadData(path);
            %cargo los estimulos
            load('Estimulos.mat');
            %Cargo las etiquetas de cada uno (algo falla ac�, arreglar)
            labels = getLabels(Estimulos);
            %cargo los monitores
            load('Monitores.mat');
            
            
            app.Estimulos = Estimulos;
            app.stimLabels = labels;
            app.Monitores = Monitores(:,1);
            %assignin('base', 'HANDLES', handles);
            propertyList = {'String', 'ForegroundColor'};
            propertyValues = {path, [0.1,0.9,0.1]};
            set(app.pathTxt, propertyList, propertyValues);
            set(app.monitorDerecho, 'Value', 1);
            set(app.monitorIzquierdo, 'Value', 1);
            guidata(hObject, handles);
        end

        % Button pushed function: openNeuronViewer
        function openNeuronViewer_Callback(app, event)
            % hObject    handle to openNeuronViewer (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %si hay neuronas cargadas abro el neuronViewer con las neuronas cargadas
            if isfield(handles, 'neurons') && ~isempty(app.neurons)
                neuronViewer(app.neurons)
            else
                %si no lo habro vacío
                neuronViewer;
            end
        end

        % Button pushed function: plotRasters
        function plotRasters_Callback(app, event)
            
            %primero veo si pedi estimulos por lista o codigo
            if app.stimMethod == "Code"
                stims = checkStimAndMonitors(app.Estimulos, app.Monitores, ...
                    app.stims2plot, app.monitorDerecho.Value, app.monitorIzquierdo.Value);
            elseif app.stimMethod == "List"
                stims = zeros(length(app.stims2plot),3);
                for i = 1:length(app.stims2plot)
                    stims(i,:) = app.Estimulos(app.stims2plot(i),:);
                end
            end
            
            %cargo los tiempos a graficar antes y después del onset del estimulo.
            %Tambien cargo el tamaño de bins calculo el número de bins que necesito
            tPre = str2double(get(app.tPreStimEdit, 'string'));
            tPost = str2double(get(app.tPostStimEdit, 'string'));
            binSize = str2double(get(app.binSize, 'string'));
            nBins = round((tPre + tPost)*(1000/binSize));
            
            %obtengo los spikes del cluster seleccionado (index indica a que estimulo
            %corresponden)
            [raster,index] = Sync(app.data{app.cluster},stims(:,2),'durations',[-tPre; tPost]);     % compute spike raster data
            
            %armo los datos para el histograma (junta todos los trials)
            [m,~] = SyncHist(raster, index,'mode', 'mean','durations',[-tPre; tPost], 'nBins', nBins);
            t = -tPre:(tPre+tPost)/(nBins-1):tPost;
            
            %creo la figura
            figure (1);clf;
            subplot(2,1,2);
            %ploteo el histograma
            bar(t,m);
            % y la adorno (acá hay que agregar detalles para los gráficos)
            hold on
            topLimit = max(m) * 1.2;
            tFinalMedio = mean(stims(:,3)-stims(:,2));
            [nStims, ~]=size(stims);
            if stimsAreUnique(96, stims(:,1))
                %fondo gris durante los periodos oscuros del estimulo 96
                R1 = rectangle('position', [-2,0, 2,topLimit]', 'FaceColor', [0.6 0.6 0.6], 'LineStyle', 'none');
                R2 = rectangle('position', [2,0, 2,topLimit]', 'FaceColor', [0.6 0.6 0.6], 'LineStyle', 'none');
                uistack(R1, 'bottom');
                uistack(R2, 'bottom');
            elseif stimsAreUnique(2, stims(:,1))
                %dibujo un looming si el estímulo es el 2
                sizeCM = 17;
                t = 0:(tFinalMedio/1000):tFinalMedio;
                y = zeros(1,length(t));
                for i = 1:length(t)
                    y(i) = 2*((20*sizeCM)/(500 - (142.5*t(i))));
                end
                y = y - y(1);
                y = y*topLimit/max(y);
                y2 = zeros(1,length(y));
                L1 = line(t,y,'Color', 'k');
                A1 = fill([t,fliplr(t)],[y, fliplr(y2)], [0.6, 0.6, 0.6]);
                uistack(L1, 'bottom');
                uistack(A1, 'bottom');
            elseif length(app.stims2plot) == 1 || stimsAreUnique([25, 26], stims(:,1))...
                    || stimsAreUnique([33 34 37 38 39 40 41 42 43 44 45 46 47 48], stims(:,1))
                %sino pongo un fondo gris durante la duracion del estimulo si hay un
                %sólo tipo de estimulo o son los dos flujos o los cuadrados
                R1 = rectangle('position', [0,0, tFinalMedio,topLimit]', 'FaceColor', [0.6 0.6 0.6], 'LineStyle', 'none');
                uistack(R1, 'bottom');
            end
            ylim([0,topLimit])
            xlim([-tPre tPost])
            subplot(2,1,1);
            %%%%%------Mi propio raster plot----%%%%%
            % figure
            % for i = 1:length(raster)
            % line([raster(i), raster(i)], [index(i)-0.5, index(i) + 0.5], 'Color', 'k')
            % end
            %%%%%-------------------------------%%%%%
            %Grafico los rasters
            PlotSync(raster,index, 'durations', [-tPre; tPost]);             % plot spike raster
            for nStim = 1:nStims
                %pongo una flechita negra al final del estimulo
                tFinal = stims(nStim, 3) - stims(nStim, 2);
                quiver ( tFinal, nStim-0.5, 0, -0.1, 'LineStyle', 'none','Color', 'k', 'Marker', 'v', 'MarkerSize', 10, 'MarkerFaceColor', 'k');
            end
            if stimsAreUnique(96, stims(:,1))
                %fondo gris durante los periodos oscuros del estimulo 96
                R1 = rectangle('position', [-2,-0.5, 2,length(stims)]', 'FaceColor', [0.6 0.6 0.6], 'LineStyle', 'none');
                R2 = rectangle('position', [2,-0.5, 2,length(stims)]', 'FaceColor', [0.6 0.6 0.6], 'LineStyle', 'none');
                uistack(R1, 'bottom');
                uistack(R2, 'bottom');
            else
                for nStim = 1:nStims
                    tFinal = stims(nStim, 3) - stims(nStim, 2);
                    quiver ( tFinal, nStim-0.5, 0, -0.1, 'LineStyle', 'none','Color', 'k', 'Marker', 'v', 'MarkerSize', 10, 'MarkerFaceColor', 'k');
                end
            end
            ylim([-0.5, nStims-0.5]);
            %creo el titulo de la figura
            titulo = ['Cluster: ', num2str(app.cluster), '   Estimulos: '];
            for i = 1:length(app.stims2plot)
                titulo = [titulo, num2str(app.stims2plot(i)), ' '];
            end
            title(titulo);
            hold off
        end

        % Button pushed function: saveCluster
        function saveCluster_Callback(app, event)
            % hObject    handle to saveCluster (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %obtengo el nombre de la neurona con la GUI clusterNameGUI
            neuron.name = clusterNameGUI;
            %si me devuelve un vector vacío es que se canceló y salgo de la función
            if isempty(neuron.name)
                return;
            end
            %cargo los datos de la neurona
            neuron.data = app.data{app.cluster};
            neuron.file = app.pathTxt.String;
            neuron.cluster = app.cluster;
            neuron.Estimulos = app.Estimulos;
            neuron.Monitores = app.Monitores;
            
            if isfield(handles, 'neurons')
                %si hay neuronas guardadas agrego la nueva neurona a la lista
                nNeurons = length(app.neurons);
                app.neurons{nNeurons+1} = neuron;
            else
                %si no asigno como la primer neurona
                app.neurons{1} = neuron;
            end
            %actualizo los datos
            set(app.clustersSaved, 'String', ['Clusters Saved: ', num2str(length(app.neurons))]);
            guidata(hObject, handles);
        end

        % Value changed function: stimByCodeEdit
        function stimByCodeEdit_Callback(app, event)
            % hObject    handle to stimByCodeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            listStr = get(hObject, 'string');
            listCell = strsplit(listStr,' ');
            app.stimMethod = "Code";
            for i = 1:length(listCell)
                stimList(i) = str2double(listCell{i});
            end
            app.stims2plot = stimList;
            set(app.stimByListEdit, 'String', '');
            guidata(hObject, handles);
        end

        % Value changed function: stimByListEdit
        function stimByListEdit_Callback(app, event)
            % hObject    handle to stimByListEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of stimByListEdit as text
            %        str2double(get(hObject,'String')) returns contents of stimByListEdit as a double
            listStr = get(hObject, 'string');
            listCell = strsplit(listStr,' ');
            app.stimMethod = "List";
            for i = 1:length(listCell)
                stimList(i) = str2double(listCell{i});
            end
            app.stims2plot = stimList;
            set(app.stimByCodeEdit, 'String', '');
            guidata(hObject, handles);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'dataAnalysisGUI';
            app.loadData = uibutton(app.figure1, 'push');
            app.loadData.ButtonPushedFcn = createCallbackFcn(app, @loadData_Callback, true);
            app.loadData.Text = 'Load Data';
            app.clusterEdit = uieditfield(app.figure1, 'text');
            app.clusterEdit.ValueChangedFcn = createCallbackFcn(app, @clusterEdit_Callback, true);
            app.clusterTxt = uilabel(app.figure1);
            app.clusterTxt.Text = 'Cluster';
            app.stimByCodeTxt = uilabel(app.figure1);
            app.stimByCodeTxt.Text = 'Estimulo/s por código';
            app.stimByCodeEdit = uieditfield(app.figure1, 'text');
            app.stimByCodeEdit.ValueChangedFcn = createCallbackFcn(app, @stimByCodeEdit_Callback, true);
            app.stimSugestionTxt = uilabel(app.figure1);
            app.stimSugestionTxt.Text = 'si es más de uno escribirlos con un espacio en el medio, ej: 2 4 12 18';
            app.plotRasters = uibutton(app.figure1, 'push');
            app.plotRasters.ButtonPushedFcn = createCallbackFcn(app, @plotRasters_Callback, true);
            app.plotRasters.Text = 'plot rasters';
            app.pathTxt = uilabel(app.figure1);
            app.pathTxt.Text = 'no data loaded';
            app.tPreStimEdit = uieditfield(app.figure1, 'text');
            app.tPreStimEdit.Value = '10';
            app.tPreStimTxt = uilabel(app.figure1);
            app.tPreStimTxt.Text = 'tiempo pre estimulo (s)';
            app.tPostStimTxt = uilabel(app.figure1);
            app.tPostStimTxt.Text = 'tiempo post estimulo (s)';
            app.tPostStimEdit = uieditfield(app.figure1, 'text');
            app.tPostStimEdit.Value = '15';
            app.stimByListTxt = uilabel(app.figure1);
            app.stimByListTxt.Text = 'Estimulo/s por número en la lista';
            app.stimByListEdit = uieditfield(app.figure1, 'text');
            app.stimByListEdit.ValueChangedFcn = createCallbackFcn(app, @stimByListEdit_Callback, true);
            app.monitorDerecho = uicheckbox(app.figure1);
            app.monitorDerecho.Text = 'Derecho';
            app.monitorIzquierdo = uicheckbox(app.figure1);
            app.monitorIzquierdo.Text = 'Izquierdo';
            app.textMonitores = uilabel(app.figure1);
            app.textMonitores.Text = 'Monitores:';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'ancho de bin (ms)';
            app.binSize = uieditfield(app.figure1, 'text');
            app.binSize.Value = '100';
            app.saveCluster = uibutton(app.figure1, 'push');
            app.saveCluster.ButtonPushedFcn = createCallbackFcn(app, @saveCluster_Callback, true);
            app.saveCluster.Text = 'Save Cluster';
            app.clustersSaved = uilabel(app.figure1);
            app.clustersSaved.Text = 'Clusters saved: 0';
            app.openNeuronViewer = uibutton(app.figure1, 'push');
            app.openNeuronViewer.ButtonPushedFcn = createCallbackFcn(app, @openNeuronViewer_Callback, true);
            app.openNeuronViewer.Text = 'neuronViewer';
            app.add2List_push = uibutton(app.figure1, 'push');
            app.add2List_push.ButtonPushedFcn = createCallbackFcn(app, @add2List_push_Callback, true);
            app.add2List_push.Text = 'Add to...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataAnalysisGUI_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @dataAnalysisGUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end